classdef gui_fixed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GrabacinenvivofrecuenciasdecortePanel  matlab.ui.container.Panel
        AplicarButton                 matlab.ui.control.Button
        FrecuenciaaltaEditField       matlab.ui.control.NumericEditField
        FrecuenciaaltaEditFieldLabel  matlab.ui.control.Label
        FrecuenciabajaEditField       matlab.ui.control.NumericEditField
        FrecuenciabajaEditFieldLabel  matlab.ui.control.Label
        SeleccindeaudioPanel          matlab.ui.container.Panel
        AudiosinfiltrarDropDownLabel  matlab.ui.control.Label
        AudiosinfiltrarDropDown       matlab.ui.control.DropDown
        ReiniciarButton               matlab.ui.control.Button
        OriginalPanel                 matlab.ui.container.Panel
        PausaButton                   matlab.ui.control.Button
        ReproducirButton              matlab.ui.control.Button
        FiltradoPanel                 matlab.ui.container.Panel
        PausaButton_fitrado           matlab.ui.control.Button
        ReproducirButton_filtrado     matlab.ui.control.Button
        GrabacinenvivoPanel           matlab.ui.container.Panel
        GrabandoLamp                  matlab.ui.control.Lamp
        DetenerButton                 matlab.ui.control.Button
        GrabarButton                  matlab.ui.control.Button
        InicioPanel                   matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        FinalPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        reproductor_grabacion % Description
        reproductor_filtrado
        grabadora1 % Description
    end
    
    methods (Access = public)
        
        
        function  [lowpass_2, delta_w_normalizada, w_c_normal]=lowpass_2(app,ventana_s,fs,fc,delta_f)
            frecuencia_nyquist=fs/2;
            frecuencia_corte_normalizada=fc/frecuencia_nyquist;
            w_c_normal=pi*frecuencia_corte_normalizada; 
            
            delta_f_normalizada=delta_f/frecuencia_nyquist;
            delta_w_normalizada=pi*delta_f_normalizada;
            
            switch ventana_s
                case 'Rectangular'
                    coeficiente=1.8;
                case 'Bartlett'
                    coeficiente=6.1;
                case 'Hann'
                    coeficiente=6.2;
                case 'Hamming'
                    coeficiente=6.6;
                case 'Blackman'
                    coeficiente=11;
            end
            
            muestras=coeficiente*pi/delta_w_normalizada;
            muestras=ceil(muestras);
            
            if ne(mod(muestras,2),0)
                muestras=muestras;
            elseif eq(mod(muestras,2),0)
                muestras=muestras+1;
            end
            
            switch ventana_s
                case 'Rectangular'
                    ventana=rectwin(muestras);
                case 'Bartlett'
                    ventana=bartlett(muestras);
                case 'Hann'
                    ventana=hann(muestras);
                case 'Hamming'
                    ventana=hamming(muestras);
                case 'Blackman'
                    ventana=blackman(muestras);
            end


            n2=[0:1:muestras-1]-(muestras-1)/2;
            impulso=(w_c_normal/pi)*sinc((w_c_normal/pi)*n2);
            
            impulso_real=impulso'.*ventana;
            
            hw_real=abs(fft(impulso_real));

            lowpass_2=impulso_real  ;

        end


        function  [highpass_2, delta_w_normalizada, w_c_normal]=highpass_2(app,ventana_s,fs,fc,delta_f)
            frecuencia_nyquist=fs/2;
            frecuencia_corte_normalizada=fc/frecuencia_nyquist;
            w_c_normal=pi*frecuencia_corte_normalizada;
            
            delta_f_normalizada=delta_f/frecuencia_nyquist;
            delta_w_normalizada=pi*delta_f_normalizada;
            
            switch ventana_s
                case 'Rectangular'
                    coeficiente=1.8;
                case 'Bartlett'
                    coeficiente=6.1;
                case 'Hann'
                    coeficiente=6.2;
                case 'Hamming'
                    coeficiente=6.6;
                case 'Blackman'
                    coeficiente=11;
            end
            
            muestras=coeficiente*pi/delta_w_normalizada;
            muestras=ceil(muestras);
            
            if ne(mod(muestras,2),0)
                muestras=muestras;
            elseif eq(mod(muestras,2),0)
                muestras=muestras+1;
            end
            
            switch ventana_s
                case 'Rectangular'
                    ventana=rectwin(muestras);
                case 'Bartlett'
                    ventana=bartlett(muestras);
                case 'Hann'
                    ventana=hann(muestras);
                case 'Hamming'
                    ventana=hamming(muestras);
                case 'Blackman'
                    ventana=blackman(muestras);
            end
            

%             
            n2=[0:1:muestras-1]-(muestras-1)/2;
            impulso=sinc(pi*n2)-sin(w_c_normal*n2)/(pi*n2);
            impulso=sinc((n2))-(w_c_normal./pi).*sinc(w_c_normal*n2/pi);
            
            impulso_real=impulso'.*ventana;
            
            hw_real=abs(fft(impulso_real));

            
            highpass_2=impulso_real;

        end
        
        function espectrograma=espectrograma(app,audio,fs,duracion_chunk_s,N,ax)

            grabacion1=audio(:,1);
            
            chunk_size=duracion_chunk_s*fs; %numero de muestras tal que cada chunk tiene duración de 15 ms
            %N=1024;  %tamaño de la transformada
            vector_inicial=length(grabacion1);
            columnas=ceil(vector_inicial/chunk_size); %cantidad de columnas del espectrograma
            
            matriz_redimensionada=zeros([chunk_size columnas]);     %espectrograma 
            
            %reorganizar el vector como matriz partiendolo en chunks o clips durante
            %los cuales la señal no cambie demasiado su contenido espectral 
            contador_filas=-1;
            for i =0:length(grabacion1)-1
                index_row=mod(i,chunk_size);    %filas
                
                if index_row<=0
                    contador_filas=contador_filas+1;    %siguiente fila cuando se llena la columna
                end
                
                matriz_redimensionada(index_row+1,contador_filas+1)=grabacion1(i+1);    %corrección de indices
            end    
            %fin matriz reorganizada indexada por tiempo-tiempo


            %antes de sacar fft a cada columna de la matriz, se define el tamaño de
            %muestras y el tamaño de transformada, el resto se rellena de ceros para
            %mejorar el aspecto del espectro


            %ventana Hanning y relleno de ceros
            matriz_relleno=zeros([N, columnas]);    %matriz del tamaño de la fft para hacer relleno de ceros
            w=hanning(chunk_size);%Ventana  del tamaño de la muestra
            w= w*sqrt(chunk_size/(w'*w));%Normalizacion de la ventana Hanning
            
            %relleno de ceros
            for i=1:columnas
                %aplicar la ventana hanning
                columna_i=w.*matriz_redimensionada(:,i);
                matriz_relleno(:,i)=[columna_i', zeros(1,N-chunk_size)];
            end
            
            %obtener transformada de fourier
            fourier=zeros([N, columnas]);
            for columns=1:columnas
                columna_i_fourier=matriz_relleno(:,columns);
                fourier(:,columns)=2*abs(fft(columna_i_fourier,N));
            end
            
            %eje temporal(columnas) del espectrograma
            time=linspace(0,length(grabacion1)/fs,columnas);
            
            %las filas indexan en la frecuencia
            filas=size(fourier);
            filas_frecuencia=filas(1);
            frecuencia=[0:filas_frecuencia-1]*fs/filas_frecuencia;
            
            
            espectrograma=imagesc(ax,time,(frecuencia(1,1:filas_frecuencia/4)),20*log10(fourier(1:filas_frecuencia/4,:))); 
            ylim(ax,[0 fs/4])
            xlim(ax,[0 length(grabacion1)/fs])
            title(ax,'Espectrograma');
            xlabel(ax,'Tiempo (s)');
            ylabel(ax,'Frecuencia (Hz)');
            %colorbar
        end
        
        function suma_audios=suma_audios(app,tipo,audio_bajo,audio_alto)
            if eq(tipo,0)
                suma_audios=audio_bajo;
            elseif eq(tipo,1)
                suma_audios=audio_alto;
            elseif eq(tipo,2)
                suma_audios=audio_bajo+audio_alto;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AudiosinfiltrarDropDown
        function AudiosinfiltrarDropDownValueChanged(app, event)
            global primera_vez;
            value = app.AudiosinfiltrarDropDown.Value;
            
            grabacion_seleccionada=value;
            
            if strcmp(grabacion_seleccionada,'Metro')==1
                [metro, Fs ]=audioread('audios/metro.wav');
                app.reproductor_grabacion=audioplayer(metro,Fs);
                grabacion=metro;
                fc_high=2125;
                delta_fh=500;
                fc_low=535;
                delta_fl=500;
                tipo=2;
% 
                duracion=0.03;
                N=2048;

            elseif strcmp(grabacion_seleccionada,'Licuadora')==1
                [licuadora, Fs ]=audioread('audios/licuadora.wav');
                app.reproductor_grabacion=audioplayer(licuadora,Fs);
                grabacion=licuadora;
                fc_high=Fs;
                delta_fh=Fs;
                fc_low=600;
                delta_fl=300;
                tipo=0;
%                 play(app.reproductor_grabacion);
                duracion=0.01;
                N=512;

            elseif strcmp(grabacion_seleccionada,'Ciudad')==1
                [ciudad, Fs ]=audioread('audios/ciudad.wav');
                app.reproductor_grabacion=audioplayer(ciudad,Fs);
                grabacion=ciudad;
                fc_high=5000;
                delta_fh=300;
                fc_low=2555;
                delta_fl=300;
                tipo=0;
%                 play(app.reproductor_grabacion);
                duracion=0.01;
                N=512;

            elseif strcmp(grabacion_seleccionada,'Bebé')==1
                [bebe, Fs ]=audioread('audios/bebe.wav');
                app.reproductor_grabacion=audioplayer(bebe,Fs);
                grabacion=bebe;
                fc_high=2640;
                delta_fh=1000;
                fc_low=659;
                delta_fl=231;
                tipo=2;
                duracion=0.03;
                N=2048;

            
            end
            t=linspace(0,length(grabacion)/Fs,length(grabacion));
            plot(app.UIAxes,t,grabacion(:,1));
            
            caption = sprintf('Grabación sin filtrar: %s', value);
            title(app.UIAxes,caption);
            xlabel(app.UIAxes,'tiempo (s)')
            xlim(app.UIAxes,[0,length(grabacion)/Fs]);


            espectrograma(app,grabacion,Fs,duracion,N,app.UIAxes2)
            
            bhi = fir1(50,0.03,'low');
            outhi = filter(bhi,1,grabacion);
            
            [filtro_pasabajas2,b,c]=lowpass_2(app,'Blackman',Fs,fc_low,delta_fl);
            salida=filter(filtro_pasabajas2,1,grabacion);
            
            [filtro_pasaaltas2,b,c]=highpass_2(app,'Blackman',Fs,fc_high,delta_fh);
            salida2=filter(filtro_pasaaltas2,1,grabacion);
            
           
            salida=salida/max(salida);
            salida2=salida2/max(salida2);
            
            salida_final=suma_audios(app,tipo,salida,salida2);
           
            
            app.reproductor_filtrado=audioplayer(salida_final,Fs);
            t=linspace(0,length(salida_final)/Fs,length(salida_final));
            plot(app.UIAxes3,t,salida_final(:,1));
            caption = sprintf('Grabación filtrada: %s', value);
            title(app.UIAxes3,caption);
            xlabel(app.UIAxes3,'tiempo (s)')
            xlim(app.UIAxes3,[0,length(grabacion)/Fs]);
            
            espectrograma(app,salida_final,Fs,duracion,N,app.UIAxes4)
            primera_vez=1;
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            
            global primera_vez;
            
            
            if primera_vez==1
                play(app.reproductor_grabacion);
                primera_vez=0;
            elseif primera_vez==0
                resume(app.reproductor_grabacion);  
            end


        end

        % Button pushed function: PausaButton
        function PausaButtonPushed(app, event)
            pause(app.reproductor_grabacion);
            
        end

        % Button pushed function: ReproducirButton_filtrado
        function ReproducirButton_filtradoPushed(app, event)
            global primera_vez;
            
            if primera_vez==1
                play(app.reproductor_filtrado);
                primera_vez=0;
            elseif primera_vez==0
                resume(app.reproductor_filtrado);  
            end
           
        end

        % Button pushed function: PausaButton_fitrado
        function PausaButton_fitradoPushed(app, event)
            pause(app.reproductor_filtrado);
           
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            stop(app.reproductor_filtrado);
            stop(app.reproductor_grabacion);
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            global Fs_grabar
            global nCanales
            global nBits
            global inicio
            
            Fs_grabar=48000;
            nCanales=1;
            nBits=16;
            app.grabadora1=audiorecorder(Fs_grabar,nBits,nCanales);
            inicio=tic;
            record(app.grabadora1); %start Recording 
            if (isrecording(app.grabadora1))==1
                app.GrabandoLamp.Color= 'red';
            else 
                app.GrabandoLamp.Color= [0.5 0.5 0.5];
            end
            
           
            pause;
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            stop(app.grabadora1) % Stop audio recording 
            global fin
            global inicio
            global Fs_grabar
            global primera_vez
            global grabacion1
            if (isrecording(app.grabadora1))==1
                app.GrabandoLamp.Color= 'red';
            else 
                app.GrabandoLamp.Color= [0.5 0.5 0.5];
            end
          

            fin=toc(inicio);

            grabacion1=getaudiodata(app.grabadora1);
            
            
            t=linspace(0,length(grabacion1)/Fs_grabar,length(grabacion1));
            plot(app.UIAxes,t,grabacion1(:,1));
            caption = sprintf('Grabación sin filtro: Grabación');
            title(app.UIAxes,caption);
            xlabel(app.UIAxes,'tiempo (s)')
            xlim(app.UIAxes,[0,length(grabacion1)/Fs_grabar]);
            app.reproductor_grabacion=audioplayer(grabacion1,Fs_grabar);
            
            fc_high=app.FrecuenciaaltaEditField.Value;
            delta_fh=250;
            fc_low=app.FrecuenciabajaEditField.Value;
            delta_fl=250;
            tipo=2;
            duracion=0.03;
            N=2048;
            
            grabacion=grabacion1;
            [filtro_pasabajas2,b,c]=lowpass_2(app,'Blackman',Fs_grabar,fc_low,delta_fl);
            salida=filter(filtro_pasabajas2,1,grabacion);
            
            [filtro_pasaaltas2,b,c]=highpass_2(app,'Blackman',Fs_grabar,fc_high,delta_fh);
            salida2=filter(filtro_pasaaltas2,1,grabacion);
            
            salida=salida/max(salida);
            salida2=salida2/max(salida2);
            salida_final=suma_audios(app,tipo,salida,salida2);
            
            app.reproductor_filtrado=audioplayer(salida_final,Fs_grabar);
            t=linspace(0,length(salida_final)/Fs_grabar,length(salida_final));
            plot(app.UIAxes3,t,salida_final(:,1));
            caption = sprintf('Grabación filtrada: Grabación');
            title(app.UIAxes3,caption);
            xlabel(app.UIAxes3,'tiempo (s)')
            xlim(app.UIAxes3,[0,length(grabacion)/Fs_grabar]);
            
            espectrograma(app,grabacion,Fs_grabar,duracion,N,app.UIAxes2)
            espectrograma(app,salida_final,Fs_grabar,duracion,N,app.UIAxes4)
            primera_vez=1;


        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
           
            global Fs_grabar
            global primera_vez
            global grabacion1
            t=linspace(0,length(grabacion1)/Fs_grabar,length(grabacion1));
            plot(app.UIAxes,t,grabacion1(:,1));
            caption = sprintf('Grabación sin filtro: Grabación');
            title(app.UIAxes,caption);
            xlabel(app.UIAxes,'tiempo (s)')
            xlim(app.UIAxes,[0,length(grabacion1)/Fs_grabar]);
            app.reproductor_grabacion=audioplayer(grabacion1,Fs_grabar);
            
            fc_high=app.FrecuenciaaltaEditField.Value;
            delta_fh=250;
            fc_low=app.FrecuenciabajaEditField.Value;
            delta_fl=250;
            tipo=2;
            duracion=0.03;
            N=2048;
            
            grabacion=grabacion1;
            [filtro_pasabajas2,b,c]=lowpass_2(app,'Blackman',Fs_grabar,fc_low,delta_fl);
            salida=filter(filtro_pasabajas2,1,grabacion);
            
            [filtro_pasaaltas2,b,c]=highpass_2(app,'Blackman',Fs_grabar,fc_high,delta_fh);
            salida2=filter(filtro_pasaaltas2,1,grabacion);
            
            salida=salida/max(salida);
            salida2=salida2/max(salida2);
            salida_final=suma_audios(app,tipo,salida,salida2);
            
            app.reproductor_filtrado=audioplayer(salida_final,Fs_grabar);
            t=linspace(0,length(salida_final)/Fs_grabar,length(salida_final));
            plot(app.UIAxes3,t,salida_final(:,1));
            caption = sprintf('Grabación filtrada: Grabación');
            title(app.UIAxes3,caption);
            xlabel(app.UIAxes3,'tiempo (s)')
            xlim(app.UIAxes3,[0,length(grabacion)/Fs_grabar]);
            
            espectrograma(app,grabacion,Fs_grabar,duracion,N,app.UIAxes2)
            espectrograma(app,salida_final,Fs_grabar,duracion,N,app.UIAxes4)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FinalPanel = uipanel(app.UIFigure);
            app.FinalPanel.Title = 'Final';
            app.GridLayout2 = uigridlayout(app.FinalPanel);
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.InicioPanel = uipanel(app.UIFigure);
            app.InicioPanel.Title = 'Inicio';
            app.GridLayout = uigridlayout(app.InicioPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GrabacinenvivoPanel = uipanel(app.UIFigure);
            app.GrabacinenvivoPanel.Title = 'Grabación en vivo';
            app.GrabarButton = uibutton(app.GrabacinenvivoPanel, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.DetenerButton = uibutton(app.GrabacinenvivoPanel, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.GrabandoLamp = uilamp(app.GrabacinenvivoPanel);
            app.FiltradoPanel = uipanel(app.UIFigure);
            app.FiltradoPanel.Title = 'Filtrado';
            app.ReproducirButton_filtrado = uibutton(app.FiltradoPanel, 'push');
            app.ReproducirButton_filtrado.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_filtradoPushed, true);
            app.ReproducirButton_filtrado.Text = 'Reproducir';
            app.PausaButton_fitrado = uibutton(app.FiltradoPanel, 'push');
            app.PausaButton_fitrado.ButtonPushedFcn = createCallbackFcn(app, @PausaButton_fitradoPushed, true);
            app.PausaButton_fitrado.Text = 'Pausa';
            app.OriginalPanel = uipanel(app.UIFigure);
            app.OriginalPanel.Title = 'Original';
            app.ReproducirButton = uibutton(app.OriginalPanel, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.PausaButton = uibutton(app.OriginalPanel, 'push');
            app.PausaButton.ButtonPushedFcn = createCallbackFcn(app, @PausaButtonPushed, true);
            app.PausaButton.Text = 'Pausa';
            app.ReiniciarButton = uibutton(app.UIFigure, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.SeleccindeaudioPanel = uipanel(app.UIFigure);
            app.SeleccindeaudioPanel.Title = 'Selección de audio';
            app.AudiosinfiltrarDropDown = uidropdown(app.SeleccindeaudioPanel);
            app.AudiosinfiltrarDropDown.Items = {'Metro', 'Licuadora', 'Ciudad', 'Bebé'};
            app.AudiosinfiltrarDropDown.ValueChangedFcn = createCallbackFcn(app, @AudiosinfiltrarDropDownValueChanged, true);
            app.AudiosinfiltrarDropDown.Value = 'Metro';
            app.AudiosinfiltrarDropDownLabel = uilabel(app.SeleccindeaudioPanel);
            app.AudiosinfiltrarDropDownLabel.Text = 'Audio sin filtrar';
            app.GrabacinenvivofrecuenciasdecortePanel = uipanel(app.UIFigure);
            app.GrabacinenvivofrecuenciasdecortePanel.Title = 'Grabación en vivo: frecuencias de corte';
            app.FrecuenciabajaEditFieldLabel = uilabel(app.GrabacinenvivofrecuenciasdecortePanel);
            app.FrecuenciabajaEditFieldLabel.Text = 'Frecuencia baja';
            app.FrecuenciabajaEditField = uieditfield(app.GrabacinenvivofrecuenciasdecortePanel, 'numeric');
            app.FrecuenciabajaEditField.Value = 2500;
            app.FrecuenciaaltaEditFieldLabel = uilabel(app.GrabacinenvivofrecuenciasdecortePanel);
            app.FrecuenciaaltaEditFieldLabel.Text = 'Frecuencia alta';
            app.FrecuenciaaltaEditField = uieditfield(app.GrabacinenvivofrecuenciasdecortePanel, 'numeric');
            app.FrecuenciaaltaEditField.Value = 3500;
            app.AplicarButton = uibutton(app.GrabacinenvivofrecuenciasdecortePanel, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_fixed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end